classdef ECGLabel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        PlotButton             matlab.ui.control.Button
        SignalButton           matlab.ui.control.Button
        LabelButton            matlab.ui.control.Button
        WorkSpaceListBoxLabel  matlab.ui.control.Label
        WorkSpaceListBox       matlab.ui.control.ListBox
        RightPanel             matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        varNames % Description
        baseVarTable
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            load displaySequence.mat           
            
            app.UITable.Visible=0;
            app.UIAxes.Visible=1;
            sig=testData{1,1};
            label=testData{1,2};
            % sig=sig(1:1000)
            % label=label(1:1000)
            
            
            X = sig;
            classes = categories(label);
            
            naSig=nan(size(sig));
            PSig=naSig;
            QRSSig=naSig;
            TSig=naSig;
            
            for i=1:length(sig)
                if label(i)=='n/a' && i < length(sig)
                    naSig([i,i+1])=[X(i), X(i+1)];
                elseif label(i)=='P' && i < length(sig)
                    PSig([i,i+1])=[X(i), X(i+1)];
                elseif label(i)=='QRS' && i < length(sig)
                    QRSSig([i,i+1])=[X(i), X(i+1)];
                elseif label(i)=='T' &&  i < length(sig)
                    TSig([i,i+1])=[X(i), X(i+1)];
                end
            end
            
            app.UIFigure
            
            
            lineWidth=2;
            % plot(sig,'g','LineWidth',lineWidth)
            hold (app.UIAxes)
            plot(app.UIAxes,QRSSig,'g','LineWidth',lineWidth)
            
            plot(app.UIAxes,naSig,'LineWidth',lineWidth,'Color',[1 0.76 0])
            
            plot(app.UIAxes,PSig,'r','LineWidth',lineWidth)
            plot(app.UIAxes,QRSSig,'g','LineWidth',lineWidth)
            plot(app.UIAxes,TSig,'b','LineWidth',lineWidth)
            % hold  ()
            
            
            Color1=[[ones(16,1);zeros(32,1);ones(16,1)],[zeros(16,1);ones(16,1);zeros(16,1);ones(16,1)*0.76],[zeros(32,1);ones(16,1);zeros(16,1)]];
            colormap(app.UIAxes,Color1);
            
            % app.UIAxes.Colormap(Color1)
            colorbar(app.UIAxes,'Ticks',[0.125,0.375,0.625,0.875],...
                'TickLabels',{'P','QRS','T','N/A'},"Direction","reverse")


        end

        % Button pushed function: SignalButton
        function SignalButtonPushed(app, event)
            % Before calling the whos function from evalin, we'll do a 
% security check that the whos function is the one provided
% by Matlab.  This reduces the risk of using evalin().
if isempty(regexp(which('whos'), 'built-in *.', 'once'))
    error('The built-in whos() function is being overshaddowed.  Do not proceed.')
end
% Get base workspace variable info
baseVarInfo = evalin('base', 'whos'); 
% Convert to table
rowNames = {baseVarInfo.name}; 
baseVarInfo = rmfield(baseVarInfo,{'name','nesting','global','sparse','complex','persistent'});
baseVarCell = [num2cell(false(size(baseVarInfo))),struct2cell(baseVarInfo)']; % selection column
app.varNames = [{'Select'}; fieldnames(baseVarInfo)];
app.baseVarTable = cell2table(baseVarCell, 'RowNames', rowNames, 'VariableNames', app.varNames);


% fig = uifigure();
% uit = uitable(fig, 'position', [20 20 500, 300],...
%     'ColumnEditable', [true, false(1,numel(app.varNames)-1)], 'Tag', 'BaseWorkspaceVarInfo');
% uit.Data = app.baseVarTable;  


        end

        % Value changed function: WorkSpaceListBox
        function WorkSpaceListBoxValueChanged(app, event)
            value = app.basevariable;
            app.WorkSpaceListBox.Value=value
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalButton = uibutton(app.LeftPanel, 'push');
            app.SignalButton.ButtonPushedFcn = createCallbackFcn(app, @SignalButtonPushed, true);
            app.SignalButton.Text = 'Signal';
            app.LabelButton = uibutton(app.LeftPanel, 'push');
            app.LabelButton.Text = 'Label';
            app.WorkSpaceListBoxLabel = uilabel(app.LeftPanel);
            app.WorkSpaceListBoxLabel.Text = 'WorkSpace';
            app.WorkSpaceListBox = uilistbox(app.LeftPanel);
            app.WorkSpaceListBox.ValueChangedFcn = createCallbackFcn(app, @WorkSpaceListBoxValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'ECG Labeling')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECGLabel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end