classdef featureExtract < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        MachineLearningAppButtonGroup  matlab.ui.container.ButtonGroup
        ClassificationButton           matlab.ui.control.ToggleButton
        RegressionButton               matlab.ui.control.ToggleButton
        OptionsLabel                   matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        Lamp                           matlab.ui.control.Lamp
        EditField                      matlab.ui.control.EditField
        ExtractFeaturesfromPretrainedNetworkLabel  matlab.ui.control.Label
        FeaturesEditFieldLabel         matlab.ui.control.Label
        FeaturesEditField              matlab.ui.control.NumericEditField
        ExtractButton                  matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        AnalyzeNetworkButton           matlab.ui.control.StateButton
        NetworkDropDown                matlab.ui.control.DropDown
        NormalizeButton                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        Features;
        labels ;
        NetName;
        netAnalyze; % Description
        OutputType; % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: NetworkDropDown
        function NetworkDropDownValueChanged(app, event)
            app.NetName = app.NetworkDropDown.Value;
            
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            %             newDisplayData = app.UITable.DisplayData;
            
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            app.UIFigure.Visible = "on";
            netName=app.NetworkDropDown.Value; %Fetch net Name
            outputType="rows";
            
            switch(lower(netName))
                case 'customnet'
                    fig = uifigure;
                    msg = strcat("Load Pretrained Network from: ");
                    title = 'Source of Network';
                    selection = uiconfirm(fig,msg,title,...
                        'Options',{'Workspace','PC'});
                    fig.Visible="off";
                    
                    switch (selection)
                        case 'PC'
                            uiopen();
                            net=net;
                            
                        case 'Workspace'
                            prompt = {'Enter Model Name:'};
                            dlgtitle = upper("Load Model");
                            dims = [1 35];
                            definput = ["net"];
                            answer = inputdlg(prompt,dlgtitle,dims,definput);
                            net = evalin('base',answer{1});
                    end
                    
                case 'alexnet'
                    disp('AlexNet Loading......')
                    try
                        net = alexnet
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = alexnet
                    end
                    inputSize= net.Layers(1).InputSize;
                    disp('AlexNet Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    layerNum=numel(net.Layers)-1
                    
                case 'squeezenet'
                    disp('SqueezeNet Loading......')
                    try
                        net = squeezenet
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = squeezenet
                    end
                    inputSize= net.Layers(1).InputSize;
                    disp('SqueezeNet Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'googlenet'
                    disp('GoogleNet Loading......')
                    try
                        net = googlenet
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = googlenet
                    end
                    inputSize= net.Layers(1).InputSize;
                    disp('GoogleNet Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'googlenetplace365'
                    disp('GoogleNet Loading......')
                    try
                        net = googlenet('Weights','places365')
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = googlenet('Weights','places365')
                    end
                    inputSize= net.Layers(1).InputSize;
                    disp('GoogleNet Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'inceptionv3'
                    disp('InceptionV3 Loading......')
                    try
                        net = inceptionv3
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = inceptionv3
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('InceptionV3 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'densenet201'
                    disp('DenseNet201 Loading......')
                    try
                        net = densenet201
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = densenet201
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('DenseNet201 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'mobilenetv2'
                    disp('MobileNetV2 Loading......')
                    try
                        net = mobilenetv2
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = mobilenetv2
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('MobileNetV2 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'resnet18'
                    disp('ResNet18 Loading......')
                    try
                        net = resnet18
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = resnet18
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('ResNet18 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'resnet50'
                    disp('ResNet50 Loading......')
                    try
                        net = resnet50
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = resnet50
                    end
                    net = resnet50;
                    inputSize= net.Layers(1).InputSize;
                    disp('ResNet50 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'resnet101'
                    disp('ResNet101 Loading......')
                    try
                        net = resnet101
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = resnet101
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('ResNet101 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'xception'
                    disp('Xception Loading......')
                    try
                        net = xception
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = xception
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('Xception Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'inceptionresnetv2'
                    disp('InceptionResNetV2 Loading......')
                    try
                        net = inceptionresnetv2
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = inceptionresnetv2
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('InceptionResNetV2 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'shufflenet'
                    disp('ShuffleNet Loading......')
                    try
                        net = shufflenet
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = shufflenet
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('ShuffleNet Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'nasnetmobile'
                    disp('NasNetMobile Loading......')
                    try
                        net = nasnetmobile
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = nasnetmobile
                    end
                    inputSize= net.Layers(1).InputSize;
                    disp('NasNetMobile Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'nasnetlarge'
                    disp('NasNetLarge Loading......')
                    try
                        net = nasnetlarge
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = nasnetlarge
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('NasNetLarge Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'darknet19'
                    disp('DarkNet19 Loading......')
                    try
                        net = darknet19
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = darknet19
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('DarkNet19 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'darknet53'
                    disp('DarkNet53 Loading......')
                    try
                        net = darknet53
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = darknet53
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('DarkNet53 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                case 'efficientnetb0'
                    disp('EfficientNetB0 Loading......')
                    try
                        net = efficientnetb0
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = efficientnetb0
                       
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('EfficientNetB0 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'vgg16'
                    disp('VGG16 Loading......')
                    try
                        net = vgg16
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = vgg16
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('VGG16 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'vgg19'
                    disp('VGG19 Loading......')
                    try
                        net = vgg19
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = vgg19
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('VGG19 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                otherwise
                    warning('There is no "%s" pretrained network. Check the pretrained network list from the link: ',netName)
                    disp('<a href = "https://www.mathworks.com/help/deeplearning/ug/pretrained-convolutional-neural-networks.html">Pretrained Deep Neural Networks</a>')
                    
            end
            layerNum=numel(net.Layers)-2;
            
            fig = uifigure;
            msg = strcat("Do you want to extract features from Layer: ",net.Layers(layerNum).Name," of ", upper(netName));
            title = 'Confirm Process';
            selection = uiconfirm(fig,msg,title,...
                'Options',{'Yes','No','Change'},...
                'DefaultOption',1,'CancelOption',2);
            fig.Visible="off";
            
            if selection == "Change"
                prompt = {'Enter Layer number:','Enter OutputAs:'};
                dlgtitle = upper(netName);
                dims = [1 35];
                definput = {num2str(layerNum),char(outputType)};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                layerNum=str2double(convertCharsToStrings(answer(1)));
                outputType=answer{2};
            end
            
            if selection == "Yes" || selection == "Change"
                %Modify Status
                app.Lamp.Color='b';
                app.EditField.Value= strcat("Extracting features from Layer: ",net.Layers(layerNum).Name," of ", upper(netName));
                
                imds=imageDatastore(uigetdir(),"IncludeSubfolders",true,"LabelSource","foldernames");
                
                delete(gcp('nocreate'))
                parpool('threads')
                
                imds.ReadFcn =@(x)customReadDatastoreImage(x,inputSize);
                
                layerName=net.Layers(layerNum).Name;
                
                Feature = activations(net,imds,layerName,'OutputAs',outputType);
                
                if outputType=="rows"
                    [~,featuresNumber]=size(Feature);
                else
                    [featuresNumber,~]=size(Feature);
                end
                
                delete(gcp('nocreate'))
                app.labels=imds.Labels;
                t=array2table(Feature);
                app.FeaturesEditField.Value=featuresNumber;
                %                 app.FeaturesEditField_2.Value= numel(imds.Files);
                
                app.UITable.Data=t;
                app.UITable.ColumnName=t.Properties.VariableNames;
                app.Features=Feature;
                clear Feature t
                
                app.Lamp.Color='g';
                app.EditField.Value=strcat("Features Extracted from Layer: ",net.Layers(layerNum).Name," of ", upper(netName));
            end
            
            app.OutputType = outputType;
            
            % Helper Function to resize the Image
            
            function data = customReadDatastoreImage(filename,inputSize)
                % code from default function:
                
                onState = warning('off', 'backtrace');
                c = onCleanup(@() warning(onState));
                data = imread(filename); % added lines:
                data = data(:,:,min(1:3, end));
                data = imresize(data,inputSize(1:2));
            end
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fig = uifigure;
            msg = strcat("Load Pretrained Network from: ");
            title = 'Save Option';
            selection = uiconfirm(fig,msg,title,...
                'Options',{'Workspace','PC'});
            fig.Visible="off";
            switch (selection)
                case 'Workspace'
                    prompt = {'Enter Feature Name:','Enter Label Name:'};
                    dlgtitle = upper("Save Feature and Label variable in Workspace");
                    dims = [1 35];
                    definput = ["Features","Labels"];
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    assignin('base',answer{1},app.Features);
                    assignin('base',answer{2},app.labels);
                    app.EditField.Value='Saved';
                    h = msgbox({sprintf(['Features and Labels are saved in ' ...
                        'the workspace named "%s" and "%s" respectively)'],answer{1},answer{2})});
                    h.GraphicsSmoothing = 'on' ;
                case 'PC'
                    Features=app.Features;
                    Labels=app.labels;
                    assignin('caller','Features',app.Features);
                    assignin('caller','Labels',app.labels);
                    uisave({'Features','Labels'},'data')
                    msgbox('Data saved','Confirmation');
            end
        end

        % Selection changed function: MachineLearningAppButtonGroup
        function MachineLearningAppButtonGroupSelectionChanged(app, event)
            %             selectedButton = app.MachineLearningAppButtonGroup.SelectedObject;
            
            if (app.ClassificationButton.Value)
                classificationLearner
            elseif (app.RegressionButton.Value)
                regressionLearner
            end
            close(app.UIFigure)
        end

        % Callback function
        function AnalyzeNetworkSwitchValueChanged(app, event)
            %             value = app.AnalyzeNetworkSwitch.Value;
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            %             value = app.TextArea.Value;
            
        end

        % Value changed function: AnalyzeNetworkButton
        function AnalyzeNetworkButtonValueChanged(app, event)
            app.netAnalyze = app.NetworkDropDown.Value;
            
            netName = app.NetworkDropDown.Value;
            app.Lamp.Color='b';
            app.EditField.Value=strcat("Analyzing Network: ",netName);
            
            
            switch(lower(netName))
                case 'alexnet'
                    disp('AlexNet Loading......')
                    try
                        net = alexnet
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = alexnet
                    end
                    inputSize= net.Layers(1).InputSize;
                    disp('AlexNet Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    layerNum=numel(net.Layers)-1
                    
                case 'squeezenet'
                    disp('SqueezeNet Loading......')
                    try
                        net = squeezenet
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = squeezenet
                    end
                    inputSize= net.Layers(1).InputSize;
                    disp('SqueezeNet Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'googlenet'
                    disp('GoogleNet Loading......')
                    try
                        net = googlenet
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = googlenet
                    end
                    inputSize= net.Layers(1).InputSize;
                    disp('GoogleNet Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'googlenetplace365'
                    disp('GoogleNet Loading......')
                    try
                        net = googlenet('Weights','places365')
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = googlenet('Weights','places365')
                    end
                    inputSize= net.Layers(1).InputSize;
                    disp('GoogleNet Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'inceptionv3'
                    disp('InceptionV3 Loading......')
                    try
                        net = inceptionv3;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = inceptionv3;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('InceptionV3 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'densenet201'
                    disp('DenseNet201 Loading......')
                    try
                        net = densenet201;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = densenet201;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('DenseNet201 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'mobilenetv2'
                    disp('MobileNetV2 Loading......')
                    try
                        net = mobilenetv2;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = mobilenetv2;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('MobileNetV2 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'resnet18'
                    disp('ResNet18 Loading......')
                    try
                        net = resnet18;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = resnet18;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('ResNet18 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'resnet50'
                    disp('ResNet50 Loading......')
                    try
                        net = resnet50;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = resnet50;
                    end
                    net = resnet50;
                    inputSize= net.Layers(1).InputSize;
                    disp('ResNet50 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'resnet101'
                    disp('ResNet101 Loading......')
                    try
                        net = resnet101;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = resnet101;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('ResNet101 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'xception'
                    disp('Xception Loading......')
                    try
                        net = xception;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = xception;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('Xception Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'inceptionresnetv2'
                    disp('InceptionResNetV2 Loading......')
                    try
                        net = inceptionresnetv2;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = inceptionresnetv2;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('InceptionResNetV2 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'shufflenet'
                    disp('ShuffleNet Loading......')
                    try
                        net = shufflenet;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = shufflenet;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('ShuffleNet Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'nasnetmobile'
                    disp('NasNetMobile Loading......')
                    try
                        net = nasnetmobile;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = nasnetmobile;
                    end
                    inputSize= net.Layers(1).InputSize;
                    disp('NasNetMobile Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'nasnetlarge'
                    disp('NasNetLarge Loading......')
                    try
                        net = nasnetlarge;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = nasnetlarge;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('NasNetLarge Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'darknet19'
                    disp('DarkNet19 Loading......')
                    try
                        net = darknet19;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = darknet19;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('DarkNet19 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'darknet53'
                    disp('DarkNet53 Loading......')
                    try
                        net = darknet53;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = darknet53;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('DarkNet53 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                case 'efficientnetb0'
                    disp('EfficientNetB0 Loading......')
                    try
                        net = efficientnetb0;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = efficientnetb0;
                        %                         throwAsCaller(MException(message('nnet_cnn:supportpackages:NotInstalled')))
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('EfficientNetB0 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'vgg16'
                    disp('VGG16 Loading......')
                    try
                        net = vgg16;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = vgg16;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('VGG16 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                case 'vgg19'
                    disp('VGG19 Loading......')
                    try
                        net = vgg19;
                    catch ME
                        fig = uifigure;
                        msg = sprintf(strcat("Check Command window to see the Error"));
                        
                        title = 'Warning for Installing the Network';
                        selection = uiconfirm(fig,msg,title,...
                            'Options',{'Ok'},"Icon","warning");
                        fig.Visible = 'off';
                        net = vgg19;
                    end
                    
                    inputSize= net.Layers(1).InputSize;
                    disp('VGG19 Loaded')
                    X = strcat("Input Size: ",num2str(inputSize));
                    disp(X)
                    
                otherwise
                    warning('There is no "%s" pretrained network. Check the pretrained network list from the link: ',netName)
                    disp('<a href = "https://www.mathworks.com/help/deeplearning/ug/pretrained-convolutional-neural-networks.html">Pretrained Deep Neural Networks</a>')
                    
            end
            analyzeNetwork(net)
        end

        % Callback function
        function ModelTypeDropDownValueChanged(app, event)
            %             value = app.ModelTypeDropDown.Value;
            if (app.ClassificationButton.Value)
                classificationLearner
            elseif (app.RegressionButton.Value)
                regressionLearner
            end
            close(app.UIFigure)
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            app.Lamp.Color='b';
            app.EditField.Value= strcat("Normaling the features");
            
            % select normalize direction
            if app.OutputType == "rows"
                dir=1;
            else
                dir=2;
            end
            
            app.Features =  featureNormalize(app.Features,dir);
            t = array2table(app.Features);
            app.UITable.Data = t;
            
            app.Lamp.Color='g';
            app.EditField.Value= strcat("Complete Normaling");
            
            function signalNormalize=featureNormalize(signal,normalizeDirection)
                
                if nargin~=2
                    normalizeDirection=2;
                end
                
                signalNormalize=rmmissing(normalize(signal,normalizeDirection,"zscore","std"));
                
            end
        end

        % Callback function
        function OptimizerMenuSelected(app, event)
            
            app.OptionsLabel.Visible = 'off';
            app.MachineLearningAppButtonGroup.Visible = 'off';
            app.RightPanel.Visible = 'off';
            app.NormalizeButton.Visible = 'off';
            app.NetworkDropDown.Visible = 'off';
            app.AnalyzeNetworkButton.Visible='off';
            app.SaveButton.Visible = 'off';
            app.ExtractButton.Visible = 'off';
            app.OptionsLabel = 'on';
            %             app.ExtractFeaturesfromPretrainedNetworkLabel.Visible ='on';
        end

        % Callback function
        function ExtracterMenuSelected(app, event)
            app.OptionsLabel.Visible = 'on';
            app.MachineLearningAppButtonGroup.Visible = 'on';
            app.RightPanel.Visible = 'on';
            app.NormalizeButton.Visible = 'on';
            app.NetworkDropDown.Visible = 'on';
            app.AnalyzeNetworkButton.Visible='on';
            app.SaveButton.Visible = 'on';
            app.ExtractButton.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MachineLearningAppButtonGroup = uibuttongroup(app.LeftPanel);
            app.MachineLearningAppButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MachineLearningAppButtonGroupSelectionChanged, true);
            app.MachineLearningAppButtonGroup.Title = 'Machine Learning App';
            app.ClassificationButton = uitogglebutton(app.MachineLearningAppButtonGroup);
            app.ClassificationButton.Text = 'Classification';
            app.RegressionButton = uitogglebutton(app.MachineLearningAppButtonGroup);
            app.RegressionButton.Text = 'Regression';
            app.RegressionButton.Value = true;
            app.OptionsLabel = uilabel(app.LeftPanel);
            app.OptionsLabel.Text = 'Options';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.Lamp = uilamp(app.RightPanel);
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 'Not Active';
            app.ExtractFeaturesfromPretrainedNetworkLabel = uilabel(app.RightPanel);
            app.ExtractFeaturesfromPretrainedNetworkLabel.Text = 'Extract Features from Pretrained Network';
            app.FeaturesEditFieldLabel = uilabel(app.RightPanel);
            app.FeaturesEditFieldLabel.Text = 'Features';
            app.FeaturesEditField = uieditfield(app.RightPanel, 'numeric');
            app.ExtractButton = uibutton(app.UIFigure, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = {'Extract'; ''};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AnalyzeNetworkButton = uibutton(app.UIFigure, 'state');
            app.AnalyzeNetworkButton.ValueChangedFcn = createCallbackFcn(app, @AnalyzeNetworkButtonValueChanged, true);
            app.AnalyzeNetworkButton.Icon = 'analyze.png';
            app.AnalyzeNetworkButton.Text = 'Analyze Network';
            app.NetworkDropDown = uidropdown(app.UIFigure);
            app.NetworkDropDown.Items = {'CustomNet', 'AlexNet', 'SqueezeNet', 'GoogleNet', 'GoogleNetPlace365', 'InceptionV3', 'DenseNet201', 'MobileNetV2', 'ResNet18', 'ResNet50', 'ResNet101', 'Xception', 'InceptionResNetV2', 'ShuffleNet', 'NasNetMobile', 'NasNetLarge', 'DarkNet19', 'DarkNet53', 'EfficientNetB0', 'VGG16', 'VGG19'};
            app.NetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkDropDownValueChanged, true);
            app.NetworkDropDown.Value = 'AlexNet';
            app.NormalizeButton = uibutton(app.UIFigure, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = featureExtract

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end